classdef Bonus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GenerateButton_2                matlab.ui.control.Button
        AudiofilenameEditField          matlab.ui.control.EditField
        AudiofilenameEditFieldLabel     matlab.ui.control.Label
        Punctul4Label                   matlab.ui.control.Label
        Punctul12Label                  matlab.ui.control.Label
        Punctul3Label                   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        GenerateSpec                    matlab.ui.control.Button
        CaracteristicaamplitudineinfuntiedeButtonGroup  matlab.ui.container.ButtonGroup
        FrecventaNenormataButton        matlab.ui.control.RadioButton
        PulsatieNormataButton           matlab.ui.control.RadioButton
        FrecventaNormataButton          matlab.ui.control.RadioButton
        N_fftDropDown                   matlab.ui.control.DropDown
        NfftLabel                       matlab.ui.control.Label
        Par1                            matlab.ui.control.Label
        Par2                            matlab.ui.control.Label
        Par3                            matlab.ui.control.Label
        Par4                            matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        ControlPannelBonusLabel         matlab.ui.control.Label
        TipulferestreiButtonGroup       matlab.ui.container.ButtonGroup
        BlackmanButton                  matlab.ui.control.RadioButton
        BartlettButton                  matlab.ui.control.RadioButton
        HannButton                      matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        DreptunghiularaButton           matlab.ui.control.RadioButton
        FsHzEditField                   matlab.ui.control.NumericEditField
        FsHzEditFieldLabel              matlab.ui.control.Label
        CastigulfiltruluiEditField      matlab.ui.control.EditField
        CastigulfiltruluiEditFieldLabel  matlab.ui.control.Label
        VectorfrecventeHzEditField      matlab.ui.control.EditField
        VectorfrecventeHzEditFieldLabel  matlab.ui.control.Label
        OrdinulfiltruluiEditField       matlab.ui.control.NumericEditField
        OrdinulfiltruluiEditFieldLabel  matlab.ui.control.Label
        UIAxesWavOut                    matlab.ui.control.UIAxes
        UIAxesSIn                       matlab.ui.control.UIAxes
        UIAxesSpecOut                   matlab.ui.control.UIAxes
        UIAxesSpecIn                    matlab.ui.control.UIAxes
        UIAxesSOut                      matlab.ui.control.UIAxes
        UIAxesIvsP                      matlab.ui.control.UIAxes
        UIAxesSelection                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        W           % Fereastra
        N           % Lungimea
        ord         % Ordinul ( n )
        FVector     % Vectorul cu frecvente
        FGain       % Castigul ( m )
        Fs          % Frecventa de esantionare
        h           % Rasp la impuls
        f           % Vector frecvente = FVector / Fs / 2
        NFFT        % Nr de esantioane
        TBDisplayed % Selection value
        H           % fft(h, NFFT)
        audioVal    % Valoare pt stocarea semnalului audio
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OrdinulfiltruluiEditField
        function OrdinF(app, event)
            app.ord = app.OrdinulfiltruluiEditField.Value;
            app.N = app.ord + 1;
            app.W = boxcar(app.N);
        end

        % Value changed function: VectorfrecventeHzEditField
        function FreqChange(app, event)
            tempVector = str2num(app.VectorfrecventeHzEditField.Value);
            app.FVector = [0, tempVector, app.Fs/2 ];
            app.f = 2*app.FVector/app.Fs;
        end

        % Value changed function: CastigulfiltruluiEditField
        function AmplChange(app, event)
            app.FGain = str2num(app.CastigulfiltruluiEditField.Value);
        end

        % Value changed function: FsHzEditField
        function ChangeFs(app, event)
            app.Fs = app.FsHzEditField.Value;
            app.NFFT = 256;
        end

        % Selection changed function: TipulferestreiButtonGroup
        function SelChanged(app, event)
            selectedButton = app.TipulferestreiButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Dreptunghiulara'
                    app.W = boxcar(app.N);
                case 'Hamming'
                    app.W = hamming(app.N);
                case 'Hann'
                    app.W = hann(app.N);
                case 'Bartlett'
                    app.W = bartlett(app.N);
                case 'Blackman'
                    app.W = blackman(app.N);
            end
        end

        % Button pushed function: GenerateButton
        function GenerateAll(app, event)
            %% Calcule pt filtre

            % Calculare Raspunsul la impuls
            app.h = fir2(app.ord, app.f, app.FGain, app.W);

            app.H = fft(app.h,app.NFFT);   %functia de transfer

            %% Creare filtru ideal
            ideal_freqs = linspace( ...
                0, app.Fs/2, app.NFFT);
            ideal_gains = interp1(app.FVector, app.FGain, ideal_freqs, 'linear', 'extrap');

            min_gain = 1e-6;
            ideal_gains(ideal_gains < min_gain) = min_gain;
            ideal_gains_db = 20*log10(ideal_gains);
        
            %% Creare filtru real

            F = -app.Fs/2 : app.Fs / app.NFFT : app.Fs / 2 - app.Fs / app.NFFT;

            %% Plotting
            plot(app.UIAxesIvsP, ideal_freqs, ideal_gains_db, "Color",[69/255, 103/255, 211/255]);
            hold(app.UIAxesIvsP, 'on');
            plot(app.UIAxesIvsP, F, 20 * log10(fftshift(abs(app.H))),"Color",[211/255, 69/255, 69/255]);
            hold(app.UIAxesIvsP, 'off');
        end

        % Value changed function: N_fftDropDown
        function UpdateNFFT(app, event)
            app.NFFT = str2num(app.N_fftDropDown.Value);
        end

        % Drop down opening function: N_fftDropDown
        function SetNFFT(app, event)
            app.NFFT = 256;
        end

        % Selection changed function: 
        % CaracteristicaamplitudineinfuntiedeButtonGroup
        function BonusChanged(app, event)
            selectedButton = app.CaracteristicaamplitudineinfuntiedeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Frecventa Normata' 
                    app.TBDisplayed = 0;
                    app.UIAxesSelection.Title.String = 'Now viewing: Freventa Normata';
                    app.UIAxesSelection.XLabel.String = "F [Hz]";
                    app.UIAxesSelection.YLabel.String = "A [dB]";

                    % Calcule si afisarea frecventei ( Normate )

                    FN = -0.5: 1/app.NFFT :0.5-1/app.NFFT; 
                    cla(app.UIAxesSelection);
                    plot(app.UIAxesSelection, FN, fftshift(abs(app.H)));
                case 'Pulsatie Normata'
                    app.TBDisplayed = 1;
                    app.UIAxesSelection.Title.String = 'Now viewing: Pulsatia Normata';
                    app.UIAxesSelection.XLabel.String = "Pulsatie";
                    app.UIAxesSelection.YLabel.String = "A [dB]";

                    % Calcule si afisarea pulsatiei ( Normata )

                    w = -pi : 2 * pi / app.NFFT : pi - 2 * pi / app.NFFT;
                    cla(app.UIAxesSelection);
                    plot(app.UIAxesSelection, w, fftshift(abs(app.H)));
                case 'Frecventa Nenormata'
                    app.TBDisplayed = 2;
                    app.UIAxesSelection.Title.String = 'Now viewing: Frecventa Nenormata';
                    app.UIAxesSelection.XLabel.String = "F [Hz]";
                    app.UIAxesSelection.YLabel.String = "A [dB]";

                    % Calcule si afisarea frecventei ( Nenormate )

                    F = -app.Fs/2 : app.Fs / app.NFFT : app.Fs / 2 - app.Fs / app.NFFT;
                    cla(app.UIAxesSelection);
                    plot(app.UIAxesSelection, F, 20 * log10(fftshift(abs(app.H))));
            end
        end

        % Button pushed function: GenerateSpec
        function GenSignal(app, event)
            %% Generarea semnalului de intrare ( Chirp )
            t = 0:1/app.Fs:1;
            chrp = chirp(t,0,1,app.Fs/2);

            %% Afisarea semnalului de Intrare ( Chirp )

            plot(app.UIAxesSIn,t, chrp);
            [S,F,T] = spectrogram(chrp, 512, 256, app.NFFT, app.Fs, 'yaxis');
            imagesc(app.UIAxesSpecIn, T, flipud(F), 10*log10(abs(S)));
            colormap(app.UIAxesSpecIn, jet);

            %% Calculare si afisarea semnalului de Iesire

            OUT = filter(app.h, 1, chrp);
            plot(app.UIAxesSOut, t, OUT);

            [S,F,T] = spectrogram(OUT, 512, 256, app.NFFT, app.Fs, 'yaxis');
            imagesc(app.UIAxesSpecOut, T, flipud(F), 10*log10(abs(S)));
            colormap(app.UIAxesSpecOut, jet);

        end

        % Value changed function: AudiofilenameEditField
        function AudioIn(app, event)
            app.audioVal = app.AudiofilenameEditField.Value;
            [semnal,app.Fs] = audioread(app.audioVal);

            OutWav = filter(app.h, 1, semnal);

            [S,F,T] = spectrogram(OutWav, 512, 256, app.NFFT, app.Fs, 'yaxis');
            imagesc(app.UIAxesWavOut, T, flipud(F), 10*log10(abs(S)));
            colormap(app.UIAxesWavOut, jet);

        end

        % Button pushed function: GenerateButton_2
        function Regen(app, event)
            [semnal,app.Fs] = audioread(app.audioVal);
            OutWav = filter(app.h, 1, semnal);

            [S,F,T] = spectrogram(OutWav, 512, 256, app.NFFT, app.Fs, 'yaxis');
            imagesc(app.UIAxesWavOut, T, flipud(F), 10*log10(abs(S)));
            colormap(app.UIAxesWavOut, jet);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSelection = uiaxes(app.UIFigure);
            title(app.UIAxesSelection, 'Now viewing: -')
            xlabel(app.UIAxesSelection, 'N/A')
            ylabel(app.UIAxesSelection, 'N/A')
            zlabel(app.UIAxesSelection, 'Z')
            app.UIAxesIvsP = uiaxes(app.UIFigure);
            title(app.UIAxesIvsP, 'Filtru Ideal vs Proiectat')
            xlabel(app.UIAxesIvsP, 'Frecventa')
            ylabel(app.UIAxesIvsP, 'Amplitudine')
            zlabel(app.UIAxesIvsP, 'Z')
            app.UIAxesSOut = uiaxes(app.UIFigure);
            title(app.UIAxesSOut, 'Semnal Iesire')
            xlabel(app.UIAxesSOut, 'Timp [ms]')
            zlabel(app.UIAxesSOut, 'Z')
            app.UIAxesSpecIn = uiaxes(app.UIFigure);
            title(app.UIAxesSpecIn, 'Spectograma Semnal Intrare')
            xlabel(app.UIAxesSpecIn, 'Timp [ms]')
            ylabel(app.UIAxesSpecIn, 'F [kHz]')
            zlabel(app.UIAxesSpecIn, 'Z')
            app.UIAxesSpecOut = uiaxes(app.UIFigure);
            title(app.UIAxesSpecOut, 'Spectograma Semnal Iesire')
            xlabel(app.UIAxesSpecOut, 'Timp [ms]')
            ylabel(app.UIAxesSpecOut, 'F [kHz]')
            zlabel(app.UIAxesSpecOut, 'Z')
            app.UIAxesSIn = uiaxes(app.UIFigure);
            title(app.UIAxesSIn, 'Semnal Intrare')
            xlabel(app.UIAxesSIn, 'Timp [ms]')
            zlabel(app.UIAxesSIn, 'Z')
            app.UIAxesWavOut = uiaxes(app.UIFigure);
            title(app.UIAxesWavOut, 'Spectograma Semnal Filtrat')
            xlabel(app.UIAxesWavOut, 'Timp [ms]')
            ylabel(app.UIAxesWavOut, 'F [kHz]')
            zlabel(app.UIAxesWavOut, 'Z')
            app.OrdinulfiltruluiEditFieldLabel = uilabel(app.UIFigure);
            app.OrdinulfiltruluiEditFieldLabel.Text = 'Ordinul filtrului';
            app.OrdinulfiltruluiEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrdinulfiltruluiEditField.ValueChangedFcn = createCallbackFcn(app, @OrdinF, true);
            app.VectorfrecventeHzEditFieldLabel = uilabel(app.UIFigure);
            app.VectorfrecventeHzEditFieldLabel.Text = 'Vector frecvente [Hz]';
            app.VectorfrecventeHzEditField = uieditfield(app.UIFigure, 'text');
            app.VectorfrecventeHzEditField.ValueChangedFcn = createCallbackFcn(app, @FreqChange, true);
            app.CastigulfiltruluiEditFieldLabel = uilabel(app.UIFigure);
            app.CastigulfiltruluiEditFieldLabel.Text = 'Castigul filtrului';
            app.CastigulfiltruluiEditField = uieditfield(app.UIFigure, 'text');
            app.CastigulfiltruluiEditField.ValueChangedFcn = createCallbackFcn(app, @AmplChange, true);
            app.FsHzEditFieldLabel = uilabel(app.UIFigure);
            app.FsHzEditFieldLabel.Text = 'Fs [ Hz ]';
            app.FsHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FsHzEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeFs, true);
            app.TipulferestreiButtonGroup = uibuttongroup(app.UIFigure);
            app.TipulferestreiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelChanged, true);
            app.TipulferestreiButtonGroup.Title = 'Tipul ferestrei';
            app.DreptunghiularaButton = uiradiobutton(app.TipulferestreiButtonGroup);
            app.DreptunghiularaButton.Text = 'Dreptunghiulara';
            app.DreptunghiularaButton.Value = true;
            app.HammingButton = uiradiobutton(app.TipulferestreiButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HannButton = uiradiobutton(app.TipulferestreiButtonGroup);
            app.HannButton.Text = 'Hann';
            app.BartlettButton = uiradiobutton(app.TipulferestreiButtonGroup);
            app.BartlettButton.Text = 'Bartlett';
            app.BlackmanButton = uiradiobutton(app.TipulferestreiButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            app.ControlPannelBonusLabel = uilabel(app.UIFigure);
            app.ControlPannelBonusLabel.Text = 'Control Pannel: Bonus';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAll, true);
            app.GenerateButton.Text = 'Genereaza';
            app.Par4 = uilabel(app.UIFigure);
            app.Par4.Text = '[';
            app.Par3 = uilabel(app.UIFigure);
            app.Par3.Text = '[';
            app.Par2 = uilabel(app.UIFigure);
            app.Par2.Text = ']';
            app.Par1 = uilabel(app.UIFigure);
            app.Par1.Text = ']';
            app.NfftLabel = uilabel(app.UIFigure);
            app.NfftLabel.Text = 'N_fft';
            app.N_fftDropDown = uidropdown(app.UIFigure);
            app.N_fftDropDown.Items = {'256', '512', '1024'};
            app.N_fftDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SetNFFT, true);
            app.N_fftDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateNFFT, true);
            app.N_fftDropDown.Value = '256';
            app.CaracteristicaamplitudineinfuntiedeButtonGroup = uibuttongroup(app.UIFigure);
            app.CaracteristicaamplitudineinfuntiedeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BonusChanged, true);
            app.CaracteristicaamplitudineinfuntiedeButtonGroup.Title = 'Caracteristica amplitudine in funtie de:';
            app.FrecventaNormataButton = uiradiobutton(app.CaracteristicaamplitudineinfuntiedeButtonGroup);
            app.FrecventaNormataButton.Text = 'Frecventa Normata';
            app.FrecventaNormataButton.Value = true;
            app.PulsatieNormataButton = uiradiobutton(app.CaracteristicaamplitudineinfuntiedeButtonGroup);
            app.PulsatieNormataButton.Text = 'Pulsatie Normata';
            app.FrecventaNenormataButton = uiradiobutton(app.CaracteristicaamplitudineinfuntiedeButtonGroup);
            app.FrecventaNenormataButton.Text = 'Frecventa Nenormata';
            app.GenerateSpec = uibutton(app.UIFigure, 'push');
            app.GenerateSpec.ButtonPushedFcn = createCallbackFcn(app, @GenSignal, true);
            app.GenerateSpec.Text = 'Genereaza semnalul';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '------------------------------------------------';
            app.Punctul3Label = uilabel(app.UIFigure);
            app.Punctul3Label.Text = 'Punctul 3';
            app.Punctul12Label = uilabel(app.UIFigure);
            app.Punctul12Label.Text = 'Punctul 1 & 2';
            app.Punctul4Label = uilabel(app.UIFigure);
            app.Punctul4Label.Text = 'Punctul 4';
            app.AudiofilenameEditFieldLabel = uilabel(app.UIFigure);
            app.AudiofilenameEditFieldLabel.Text = 'Audio file name:';
            app.AudiofilenameEditField = uieditfield(app.UIFigure, 'text');
            app.AudiofilenameEditField.ValueChangedFcn = createCallbackFcn(app, @AudioIn, true);
            app.GenerateButton_2 = uibutton(app.UIFigure, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @Regen, true);
            app.GenerateButton_2.Text = 'Re-Genereaza';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bonus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end